classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        encipherBtn  matlab.ui.control.Button
        decipherBtn  matlab.ui.control.Button
        Label        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: encipherBtn
        function encipherBtnButtonPushed(app, event)
            scriptname1='Blowfish.m';
            run(scriptname1);
            scriptname2='dec2str.m';
            run(scriptname2);
            scriptname3='str2dec.m';
            run(scriptname3);
            scriptname4='Blowfish.Imp.m';
            run(scriptname4);
            
            [xl,xr] = objBF.Blowfish_encipher(str2dec(datal),str2dec(datar));
            enciphered=strcat(dec2str(xl),dec2str(xr));
            set(app.Label,'Text',enciphered);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.encipherBtn = uibutton(app.UIFigure, 'push');
            app.encipherBtn.ButtonPushedFcn = createCallbackFcn(app, @encipherBtnButtonPushed, true);
            app.encipherBtn.Text = 'encipher';
            app.decipherBtn = uibutton(app.UIFigure, 'push');
            app.decipherBtn.Text = 'decipher';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '--';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end